classdef DRTools < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DRToolsUIFigure            matlab.ui.Figure
        ScatterPlot                matlab.ui.control.UIAxes
        DataIOPanel                matlab.ui.container.Panel
        LoadButton                 matlab.ui.control.Button
        SaveButton                 matlab.ui.control.Button
        DimensionalityReductionOptionsPanel  matlab.ui.container.Panel
        MethodDropDownLabel        matlab.ui.control.Label
        MethodDropDown             matlab.ui.control.DropDown
        RankEditFieldLabel         matlab.ui.control.Label
        RankEditField              matlab.ui.control.NumericEditField
        FitPlay                    matlab.ui.control.Button
        NDims                      matlab.ui.control.DropDown
        VisualizationOptionsPanel  matlab.ui.container.Panel
        PopPlotButton              matlab.ui.control.Button
        PopVarButton               matlab.ui.control.Button
        PopBaseButton              matlab.ui.control.Button
        PCXIndex                   matlab.ui.control.NumericEditField
        NextPCXButton              matlab.ui.control.Button
        PrevPCXButton              matlab.ui.control.Button
        NextPCYButton              matlab.ui.control.Button
        PrevPCYButton              matlab.ui.control.Button
        PCYIndex                   matlab.ui.control.NumericEditField
        PCLabel                    matlab.ui.control.Label
        BasisPlot                  matlab.ui.control.UIAxes
        BasisIndex                 matlab.ui.control.NumericEditField
        PrevBasisButton            matlab.ui.control.Button
        NextBasisButton            matlab.ui.control.Button
        BasisLabel                 matlab.ui.control.Label
        LabelOptionsPanel          matlab.ui.container.Panel
        LoadButton_2               matlab.ui.control.Button
    end


    properties (Access = private)
        
        LoadedDataset = [];
        LoadedLabel = [];
        
        CurrentPC = [];
        CurrentBases = [];
        CurrentVar = [];
        
        CurrentScatterX = [];
        CurrentScatterY = [];
        CurrentBasis = [];
        
    end

    methods (Access = private)
    
        function plotScatter(app)
            if ~isempty(app.LoadedLabel)
                scatter(app.ScatterPlot,app.CurrentScatterX,app.CurrentScatterY,25,app.LoadedLabel,'filled');
            else
                scatter(app.ScatterPlot,app.CurrentScatterX,app.CurrentScatterY,25,'filled');
            end
            axis(app.ScatterPlot,'tight');
            grid(app.ScatterPlot,'on');
        end
        
        function plotBasis(app)
            current = app.CurrentBasis;
            
            % Temporary Formatting
            switch app.NDims.Value
                case '3D'
                    DataSize = repmat(round((length(current)).^(1/3)),[1 3]);
                    current = reshape(current,DataSize);
                    current = current(:,:,round(DataSize(1)/2));
                case '2D'
                    DataSize = repmat(round((length(current)).^(1/2)),[1 2]);
                    current = reshape(current,DataSize);
            end
                   
            app.CurrentBasis = current;
            
            imagesc(app.BasisPlot,current);
        end
        
    end


    methods (Access = private)

        % Button pushed function: FitPlay
        function FitPlayButtonPushed(app, event)
            
            switch app.MethodDropDown.Value
                case 'PCA'
                    
                    [PC,Var,M,Basis]=PCAConstruct(app.LoadedDataset,app.RankEditField.Value);
                    
                    app.CurrentPC = PC;
                    app.CurrentBases = [M' Basis];   
                    app.CurrentVar = Var;
                    
                    app.CurrentScatterX = PC(:,1);
                    app.CurrentScatterY = PC(:,2);
                    app.CurrentBasis = M;
                    
                    app.PCXIndex.Limits(2) = app.RankEditField.Value;
                    app.PCYIndex.Limits(2) = app.RankEditField.Value;
                    app.BasisIndex.Limits(2) = app.RankEditField.Value;
                    
                    plotScatter(app)
                    plotBasis(app)
            end


        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.LoadButton.Enable = 'off';
            [FileName,PathName,~] = uigetfile('*.mat','Select Dataset','Stats.mat');
            
            if FileName == 0   
                errordlg('No valid file has been selected!');
                app.LoadButton.Enable = 'on';
                return
            end
            
            FilePath = fullfile(PathName,FileName);
            
            S = uiimport(FilePath);
            
            if isempty(S)
                errordlg('No valid file has been selected!');
                app.LoadButton.Enable = 'on';
                return
            end
            
            Snames = fieldnames(S);
            current = S.(Snames{1});
            
            if ~ismatrix(current)
                errordlg('No valid file has been selected!');
                app.LoadButton.Enable = 'on';
                return
            end
               
            app.LoadedDataset = current;    
            app.LoadButton.Enable = 'on';
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            app.LoadButton_2.Enable = 'off';
            [FileName,PathName,~] = uigetfile('*.mat','Select Label','Label.mat');
            
            if FileName == 0   
                errordlg('No valid file has been selected!');
                app.LoadButton_2.Enable = 'on';
                return
            end
            
            FilePath = fullfile(PathName,FileName);
            
            S = uiimport(FilePath);
            
            if isempty(S)
                errordlg('No valid file has been selected!');
                app.LoadButton_2.Enable = 'on';
                return
            end
            
            Snames = fieldnames(S);
            current = S.(Snames{1});
            
            if ~ismatrix(current)
                errordlg('No valid file has been selected!');
                app.LoadButton_2.Enable = 'on';
                return
            end
            
            if size(current,2) > size(current,1)
                current = current';
            end
            
            if size(current,2) ~= 1
                errordlg('No valid file has been selected!');
                app.LoadButton_2.Enable = 'on';
                return
            end
               
            app.LoadedLabel = current;    
            app.LoadButton_2.Enable = 'on';
        end

        % Button pushed function: NextBasisButton
        function NextBasisButtonPushed(app, event)
            if app.BasisIndex.Value < app.BasisIndex.Limits(2)
                app.BasisIndex.Value = app.BasisIndex.Value + 1;
                app.CurrentBasis = app.CurrentBases(:,app.BasisIndex.Value + 1);
                plotBasis(app);    
            end
        end

        % Button pushed function: NextPCXButton
        function NextPCXButtonPushed(app, event)
            if app.PCXIndex.Value < app.PCXIndex.Limits(2)
                app.PCXIndex.Value = app.PCXIndex.Value + 1;
                app.CurrentScatterX = app.CurrentPC(:,app.PCXIndex.Value);
                plotScatter(app);
            end
        end

        % Button pushed function: NextPCYButton
        function NextPCYButtonPushed(app, event)
            if app.PCYIndex.Value < app.PCYIndex.Limits(2)
                app.PCYIndex.Value = app.PCYIndex.Value + 1;
                app.CurrentScatterY = app.CurrentPC(:,app.PCYIndex.Value);
                plotScatter(app);
            end
        end

        % Button pushed function: PopBaseButton
        function PopBaseButtonPushed(app, event)
            h3 = figure;
            imagesc(app.CurrentBasis);
            axis('image');
        end

        % Button pushed function: PopPlotButton
        function PopPlotButtonPushed(app, event)
            h1 = figure;
            scatter(app.CurrentScatterX,app.CurrentScatterY,25,app.LoadedLabel,'filled');
            axis('square');
            axis('tight');
            grid('on');
        end

        % Button pushed function: PopVarButton
        function PopVarButtonPushed(app, event)
            h2 = figure;
            visualize_pca_variance(app.CurrentVar,length(app.CurrentVar));
            axis('square');
            axis('tight');
            grid('on');
        end

        % Button pushed function: PrevBasisButton
        function PrevBasisButtonPushed(app, event)
            if app.BasisIndex.Value > app.BasisIndex.Limits(1)
                app.BasisIndex.Value = app.BasisIndex.Value - 1;
                app.CurrentBasis = app.CurrentBases(:,app.BasisIndex.Value + 1);
                plotBasis(app);
            end
        end

        % Button pushed function: PrevPCXButton
        function PrevPCXButtonPushed(app, event)
            if app.PCXIndex.Value > app.PCXIndex.Limits(1)
                app.PCXIndex.Value = app.PCXIndex.Value - 1;
                app.CurrentScatterX = app.CurrentPC(:,app.PCXIndex.Value);
                plotScatter(app); 
            end
        end

        % Button pushed function: PrevPCYButton
        function PrevPCYButtonPushed(app, event)
            if app.PCYIndex.Value > app.PCYIndex.Limits(1)
                app.PCYIndex.Value = app.PCYIndex.Value - 1;
                app.CurrentScatterY = app.CurrentPC(:,app.PCYIndex.Value);
                plotScatter(app); 
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            switch app.MethodDropDown.Value
                case 'PCA'
                    PC = app.CurrentPC;
                    Bases = app.CurrentBases;
                    Var = app.CurrentVar;
                    uisave({'PC','Bases','Var'},'PC.mat');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DRToolsUIFigure = uifigure;
            app.DRToolsUIFigure.Name = 'DR Tools';
            app.ScatterPlot = uiaxes(app.DRToolsUIFigure);
            title(app.ScatterPlot, 'Reduced Order Representation')
            xlabel(app.ScatterPlot, 'PC      ')
            app.DataIOPanel = uipanel(app.DRToolsUIFigure);
            app.DataIOPanel.Title = 'Data IO';
            app.LoadButton = uibutton(app.DataIOPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Icon = 'open_folder_blue-512.png';
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.DataIOPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'save.png';
            app.SaveButton.Text = 'Save';
            app.DimensionalityReductionOptionsPanel = uipanel(app.DRToolsUIFigure);
            app.DimensionalityReductionOptionsPanel.Title = 'Dimensionality Reduction Options';
            app.MethodDropDownLabel = uilabel(app.DimensionalityReductionOptionsPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.DimensionalityReductionOptionsPanel);
            app.MethodDropDown.Items = {'PCA'};
            app.MethodDropDown.Value = 'PCA';
            app.RankEditFieldLabel = uilabel(app.DimensionalityReductionOptionsPanel);
            app.RankEditFieldLabel.Text = 'Rank';
            app.RankEditField = uieditfield(app.DimensionalityReductionOptionsPanel, 'numeric');
            app.RankEditField.Limits = [2 Inf];
            app.RankEditField.Value = 10;
            app.FitPlay = uibutton(app.DimensionalityReductionOptionsPanel, 'push');
            app.FitPlay.ButtonPushedFcn = createCallbackFcn(app, @FitPlayButtonPushed, true);
            app.FitPlay.Icon = 'play.png';
            app.FitPlay.Text = '';
            app.NDims = uidropdown(app.DimensionalityReductionOptionsPanel);
            app.NDims.Items = {'3D', '2D'};
            app.NDims.Value = '3D';
            app.VisualizationOptionsPanel = uipanel(app.DRToolsUIFigure);
            app.VisualizationOptionsPanel.Title = 'Visualization Options';
            app.PopPlotButton = uibutton(app.VisualizationOptionsPanel, 'push');
            app.PopPlotButton.ButtonPushedFcn = createCallbackFcn(app, @PopPlotButtonPushed, true);
            app.PopPlotButton.Icon = 'Plot.PNG';
            app.PopPlotButton.Text = 'Pop Plot';
            app.PopVarButton = uibutton(app.VisualizationOptionsPanel, 'push');
            app.PopVarButton.ButtonPushedFcn = createCallbackFcn(app, @PopVarButtonPushed, true);
            app.PopVarButton.Icon = 'Var.PNG';
            app.PopVarButton.Text = 'Pop Var';
            app.PopBaseButton = uibutton(app.VisualizationOptionsPanel, 'push');
            app.PopBaseButton.ButtonPushedFcn = createCallbackFcn(app, @PopBaseButtonPushed, true);
            app.PopBaseButton.Icon = 'Basis.PNG';
            app.PopBaseButton.Text = 'Pop Base';
            app.PCXIndex = uieditfield(app.DRToolsUIFigure, 'numeric');
            app.PCXIndex.Limits = [1 Inf];
            app.PCXIndex.Editable = 'off';
            app.PCXIndex.Value = 1;
            app.NextPCXButton = uibutton(app.DRToolsUIFigure, 'push');
            app.NextPCXButton.ButtonPushedFcn = createCallbackFcn(app, @NextPCXButtonPushed, true);
            app.NextPCXButton.Icon = 'icon_next_frame-512.png';
            app.NextPCXButton.Text = '';
            app.PrevPCXButton = uibutton(app.DRToolsUIFigure, 'push');
            app.PrevPCXButton.ButtonPushedFcn = createCallbackFcn(app, @PrevPCXButtonPushed, true);
            app.PrevPCXButton.Icon = 'icon_previous_frame-512.png';
            app.PrevPCXButton.Text = '';
            app.NextPCYButton = uibutton(app.DRToolsUIFigure, 'push');
            app.NextPCYButton.ButtonPushedFcn = createCallbackFcn(app, @NextPCYButtonPushed, true);
            app.NextPCYButton.Icon = 'up.png';
            app.NextPCYButton.Text = '';
            app.PrevPCYButton = uibutton(app.DRToolsUIFigure, 'push');
            app.PrevPCYButton.ButtonPushedFcn = createCallbackFcn(app, @PrevPCYButtonPushed, true);
            app.PrevPCYButton.Icon = 'down.png';
            app.PrevPCYButton.Text = '';
            app.PCYIndex = uieditfield(app.DRToolsUIFigure, 'numeric');
            app.PCYIndex.Limits = [1 Inf];
            app.PCYIndex.Editable = 'off';
            app.PCYIndex.Value = 2;
            app.PCLabel = uilabel(app.DRToolsUIFigure);
            app.PCLabel.Text = 'PC';
            app.BasisPlot = uiaxes(app.DRToolsUIFigure);
            title(app.BasisPlot, 'Basis Representation')
            app.BasisPlot.XTickLabel = '';
            app.BasisPlot.YTickLabel = '';
            app.BasisIndex = uieditfield(app.DRToolsUIFigure, 'numeric');
            app.BasisIndex.Limits = [0 Inf];
            app.BasisIndex.Editable = 'off';
            app.PrevBasisButton = uibutton(app.DRToolsUIFigure, 'push');
            app.PrevBasisButton.ButtonPushedFcn = createCallbackFcn(app, @PrevBasisButtonPushed, true);
            app.PrevBasisButton.Icon = 'icon_previous_frame-512.png';
            app.PrevBasisButton.Text = '';
            app.NextBasisButton = uibutton(app.DRToolsUIFigure, 'push');
            app.NextBasisButton.ButtonPushedFcn = createCallbackFcn(app, @NextBasisButtonPushed, true);
            app.NextBasisButton.Icon = 'icon_next_frame-512.png';
            app.NextBasisButton.Text = '';
            app.BasisLabel = uilabel(app.DRToolsUIFigure);
            app.BasisLabel.Text = 'Basis';
            app.LabelOptionsPanel = uipanel(app.DRToolsUIFigure);
            app.LabelOptionsPanel.Title = 'Label Options';
            app.LoadButton_2 = uibutton(app.LabelOptionsPanel, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Icon = 'Label.png';
            app.LoadButton_2.Text = 'Load';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DRTools

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DRToolsUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DRToolsUIFigure)
        end
    end
end